classdef CerestimDeviceManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StatusTab                       matlab.ui.container.Tab
        ModuleStatusTable               matlab.ui.control.Table
        DeviceConnectedLamp             matlab.ui.control.Lamp
        DeviceConnectedLampLabel        matlab.ui.control.Label
        LockCheckLamp                   matlab.ui.control.Lamp
        LockCheckLampLabel              matlab.ui.control.Label
        SafetyCheckLamp                 matlab.ui.control.Lamp
        SafetyCheckLampLabel            matlab.ui.control.Label
        DeviceInfoTab                   matlab.ui.container.Tab
        ModuleVersionTable              matlab.ui.control.Table
        ProtocolVersionEditField        matlab.ui.control.EditField
        ProtocolVersionLabel            matlab.ui.control.Label
        MotherboardVersionEditField     matlab.ui.control.EditField
        MotherboardVersionEditFieldLabel  matlab.ui.control.Label
        SerialNumberEditField           matlab.ui.control.EditField
        SerialNumberEditFieldLabel      matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        ApplyButton                     matlab.ui.control.Button
        MaximumVoltageDropDown          matlab.ui.control.DropDown
        MaximumVoltageDropDownLabel     matlab.ui.control.Label
        MaximumPhaseChargeEditField     matlab.ui.control.NumericEditField
        MaximumPhaseChargeEditFieldLabel  matlab.ui.control.Label
        MaximumFrequencyEditField       matlab.ui.control.NumericEditField
        MaximumFrequencyEditFieldLabel  matlab.ui.control.Label
        MaximumAmplitudeEditField       matlab.ui.control.NumericEditField
        MaximumAmplitudeEditFieldLabel  matlab.ui.control.Label
        TestModulesTab                  matlab.ui.container.Tab
        TestModulesButton               matlab.ui.control.Button
        ModuleCheckTable                matlab.ui.control.Table
        TestImpedenceTab                matlab.ui.container.Tab
        TestImpedenceButton             matlab.ui.control.Button
        ImpedenceCheckTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        Cerestim % Description
        TableCenterStyle
    end
    
    methods (Access = private)
        
        function writeDeviceInfoToApp(app)
            %% GetVariables
            DeviceInfo = app.Cerestim.deviceInfo();
            HardwareLimits = app.Cerestim.getHardwareValues();
            usbAddress = app.Cerestim.usbAddress();
            MaxOutputValues = app.Cerestim.stimulusMaxValue();

            %% Status Info
            if app.Cerestim.isConnected()
                app.DeviceConnectedLamp.Color = [0 1 0];
            else
                app.DeviceConnectedLamp.Color = [1 0 0];
            end
            if ~app.Cerestim.isSafetyDisabled()
                app.SafetyCheckLamp.Color = [0 1 0];
            else
                app.SafetyCheckLamp.Color = [1 0 0];
            end
            if ~app.Cerestim.isLocked()
                app.LockCheckLamp.Color = [0 1 0];
            else
                app.LockCheckLamp.Color = [1 0 0];
            end
            app.ModuleStatusTable.Data = table((1:length(DeviceInfo.moduleStatus))',DeviceInfo.moduleStatus);

            %% Device Info
            app.SerialNumberEditField.Value = DeviceInfo.serialNo;
            app.MotherboardVersionEditField.Value = DeviceInfo.motherboardVersion;
            app.ProtocolVersionEditField.Value = DeviceInfo.protocolVersion;
            app.ModuleVersionTable.Data = table((1:length(DeviceInfo.moduleVersion))',DeviceInfo.moduleVersion);

            %% Settings
            app.MaximumPhaseChargeEditField.Value = MaxOutputValues.phaseCharge;
            app.MaximumPhaseChargeEditField.Limits = [0 HardwareLimits.charge];
            app.MaximumAmplitudeEditField.Value = MaxOutputValues.amplitude;
            app.MaximumAmplitudeEditField.Limits = [0 HardwareLimits.amp];
            app.MaximumFrequencyEditField.Value = MaxOutputValues.frequency;
            app.MaximumFrequencyEditField.Limits = [0 HardwareLimits.maxFreq];
            app.MaximumVoltageDropDown.Value = MaxOutputValues.voltage;
            tmp = HardwareLimits.minCompVoltage:HardwareLimits.maxCompVoltage;
            [items,itemsInd] = intersect(app.MaximumVoltageDropDown.ItemsData,tmp,'stable');
            app.MaximumVoltageDropDown.Items = app.MaximumVoltageDropDown.Items(itemsInd);
            app.MaximumVoltageDropDown.ItemsData = items;


        end
        
        function ChangeCerestimLimitSettings(app)
            volt = app.MaximumVoltageDropDown.Value;
            amp = app.MaximumAmplitudeEditField.Value;
            phasecharge = app.MaximumPhaseChargeEditField.Value;
            freq = app.MaximumFrequencyEditField.Value;
            app.Cerestim.stimulusMaxValue(volt,amp,phasecharge,freq)
        end
        
        function TestCerestimModules(app)
            ModuleCheck = app.Cerestim.testModules();
            tmpCell = cell(length(ModuleCheck.modulesStatus),1);
            for i = 1:numel(tmpCell)
                switch ModuleCheck.modulesStatus(i)
                    case 0
                        tmpCell{i} = 'Unavailable';
                    case 1
                        tmpCell{i} = 'Enabled';
                    case 2
                        tmpCell{i} = 'Disabled';
                    case 3
                        tmpCell{i} = 'Normal Voltage Levels';
                    case 4
                        tmpCell{i} = 'Voltage Levels Below Normal';
                end
            end
            app.ModuleCheckTable.Data = table((1:length(tmpCell))',tmpCell);
        end
        
        function TestCerestimImpedence(app)
            ImpedenceTest = app.Cerestim.testElectrodes();
            tmp = [cellstr('Ground');cellstr(int2str((1:96)'))];
            app.ImpedenceCheckTable.Data = table(tmp,ImpedenceTest.impedance);
        end
        
        function StyleTables(app)
            addStyle(app.ModuleStatusTable,app.TableCenterStyle)
            addStyle(app.ModuleVersionTable,app.TableCenterStyle)
            addStyle(app.ModuleCheckTable,app.TableCenterStyle)
            addStyle(app.ImpedenceCheckTable,app.TableCenterStyle)
        end

        function EndCerestimConnection(app)
            if app.Cerestim.isConnected()
                app.Cerestim.disconnect()
            end
            delete(app.Cerestim)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            if nargin==2
                app.CallingApp = callingApp;
                app.Cerestim = app.CallingApp.Cerestim;
            else
                try 
                    app.Cerestim = cerestim96();
                    Serial = app.Cerestim.scanForDevices();
                    app.Cerestim.selectDevice(Serial);
                    app.Cerestim.connect();
                catch ME
                    uialert(app.UIFigure,ME.message,'Cerestim Error','Icon','error');
                end
            end
            writeDeviceInfoToApp(app)
            app.TableCenterStyle = uistyle('HorizontalAlignment','center');
            StyleTables(app)
        end

        % Button pushed function: TestModulesButton
        function TestModulesButtonPushed(app, event)
            TestCerestimModules(app)
        end

        % Value changed function: MaximumAmplitudeEditField, 
        % ...and 3 other components
        function SettingsChanged(app, event)
            app.ApplyButton.Enable = 1;
            app.Lamp.Color = [1 1 0];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ChangeCerestimLimitSettings(app)
            app.ApplyButton.Enable = 0;
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: TestImpedenceButton
        function TestImpedenceButtonPushed(app, event)
            TestCerestimImpedence(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            EndCerestimConnection(app)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cerestim Device Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StatusTab = uitab(app.TabGroup);
            app.StatusTab.Title = 'Status';
            app.SafetyCheckLampLabel = uilabel(app.StatusTab);
            app.SafetyCheckLampLabel.Text = 'Safety Check:';
            app.SafetyCheckLamp = uilamp(app.StatusTab);
            app.LockCheckLampLabel = uilabel(app.StatusTab);
            app.LockCheckLampLabel.Text = 'Lock Check:';
            app.LockCheckLamp = uilamp(app.StatusTab);
            app.DeviceConnectedLampLabel = uilabel(app.StatusTab);
            app.DeviceConnectedLampLabel.Text = 'Device Connected:';
            app.DeviceConnectedLamp = uilamp(app.StatusTab);
            app.ModuleStatusTable = uitable(app.StatusTab);
            app.ModuleStatusTable.ColumnName = {'Module ID'; 'Status'};
            app.ModuleStatusTable.RowName = {};
            app.DeviceInfoTab = uitab(app.TabGroup);
            app.DeviceInfoTab.Title = 'Device Info';
            app.SerialNumberEditFieldLabel = uilabel(app.DeviceInfoTab);
            app.SerialNumberEditFieldLabel.Text = 'Serial Number:';
            app.SerialNumberEditField = uieditfield(app.DeviceInfoTab, 'text');
            app.SerialNumberEditField.Editable = 'off';
            app.MotherboardVersionEditFieldLabel = uilabel(app.DeviceInfoTab);
            app.MotherboardVersionEditFieldLabel.Text = 'Motherboard Version:';
            app.MotherboardVersionEditField = uieditfield(app.DeviceInfoTab, 'text');
            app.MotherboardVersionEditField.Editable = 'off';
            app.ProtocolVersionLabel = uilabel(app.DeviceInfoTab);
            app.ProtocolVersionLabel.Text = 'Protocol Version:';
            app.ProtocolVersionEditField = uieditfield(app.DeviceInfoTab, 'text');
            app.ProtocolVersionEditField.Editable = 'off';
            app.ModuleVersionTable = uitable(app.DeviceInfoTab);
            app.ModuleVersionTable.ColumnName = {'Module ID'; 'Version'};
            app.ModuleVersionTable.RowName = {};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MaximumAmplitudeEditFieldLabel = uilabel(app.SettingsTab);
            app.MaximumAmplitudeEditFieldLabel.Text = 'Maximum Amplitude:';
            app.MaximumAmplitudeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MaximumAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.MaximumFrequencyEditFieldLabel = uilabel(app.SettingsTab);
            app.MaximumFrequencyEditFieldLabel.Text = 'Maximum Frequency:';
            app.MaximumFrequencyEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MaximumFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.MaximumPhaseChargeEditFieldLabel = uilabel(app.SettingsTab);
            app.MaximumPhaseChargeEditFieldLabel.Text = 'Maximum Phase Charge:';
            app.MaximumPhaseChargeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MaximumPhaseChargeEditField.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.MaximumVoltageDropDownLabel = uilabel(app.SettingsTab);
            app.MaximumVoltageDropDownLabel.Text = 'Maximum Voltage:';
            app.MaximumVoltageDropDown = uidropdown(app.SettingsTab);
            app.MaximumVoltageDropDown.Items = {'4.7', '5.3', '5.9', '6.5', '7.1', '7.7', '8.3', '8.9', '9.5'};
            app.MaximumVoltageDropDown.ItemsData = [7 8 9 10 11 12 13 14 15];
            app.MaximumVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.MaximumVoltageDropDown.Value = 7;
            app.ApplyButton = uibutton(app.SettingsTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Lamp = uilamp(app.SettingsTab);
            app.TestModulesTab = uitab(app.TabGroup);
            app.TestModulesTab.Title = 'Test Modules';
            app.ModuleCheckTable = uitable(app.TestModulesTab);
            app.ModuleCheckTable.ColumnName = {'Module'; 'Status'};
            app.ModuleCheckTable.RowName = {};
            app.TestModulesButton = uibutton(app.TestModulesTab, 'push');
            app.TestModulesButton.ButtonPushedFcn = createCallbackFcn(app, @TestModulesButtonPushed, true);
            app.TestModulesButton.Text = 'Test Modules';
            app.TestImpedenceTab = uitab(app.TabGroup);
            app.TestImpedenceTab.Title = 'Test Impedence';
            app.ImpedenceCheckTable = uitable(app.TestImpedenceTab);
            app.ImpedenceCheckTable.ColumnName = {'Electrode'; 'Impedence'};
            app.ImpedenceCheckTable.RowName = {};
            app.TestImpedenceButton = uibutton(app.TestImpedenceTab, 'push');
            app.TestImpedenceButton.ButtonPushedFcn = createCallbackFcn(app, @TestImpedenceButtonPushed, true);
            app.TestImpedenceButton.Text = 'Test Impedence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CerestimDeviceManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end